classdef AnimeAndData_Show < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        thSlider         matlab.ui.control.Slider
        PlayButton       matlab.ui.control.StateButton
        tEditFieldLabel  matlab.ui.control.Label
        tEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        pAnimes        matlab.graphics.chart.primitive.Scatter
        time           double
        Global_Origin  rigidBodyTree
        Config_Datas   double
        pauseTime      double
        sliders        matlab.ui.control.Slider
        figure_ID
        ax
    end
    
    
    methods (Access = private)
        
        function setPsandLines(app,targetIndex)
            CameraPosition_tmp = app.ax.CameraPosition;
            CameraViewAngle_tmp = app.ax.CameraViewAngle;
%             xlim_tmp = app.ax.XLim;
%             ylim_tmp = app.ax.YLim;
%             zlim_tmp = app.ax.ZLim;
            show(app.Global_Origin, app.Config_Datas(targetIndex,:),'PreservePlot',true, 'Parent', app.ax);
%             xlim(app.ax, xlim_tmp)
%             ylim(app.ax, ylim_tmp)
%             zlim(app.ax, zlim_tmp)
            app.ax.CameraPosition = CameraPosition_tmp;
            app.ax.CameraViewAngle = CameraViewAngle_tmp;
        end
        
        function setSliders(app, value)
            for i = app.sliders
                i.Value = value;
            end
        end


    end
    
    methods (Access = public)
        function restartupFcn(app, time, Global_Origin, Config_Datas)
            startupFcn(app, time, Global_Origin, Config_Datas)
            setSliders(app, 1)
        end
        
        function FirstPlay(app)
            app.PlayButton.Value = true;
            startTime = floor(app.thSlider.Value);
            if startTime ~= app.thSlider.Limits(2)
                for i = [startTime:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time), length(app.time)]
                    if ~app.PlayButton.Value
                        break
                    end
                    
                    setSliders(app, i);
                    setPsandLines(app,i);
                    app.tEditField.Value = app.time(i);
                    
                    drawnow
                    pause(app.pauseTime);
                end
            else
                for i = [1:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time),...
                        length(app.time)]
                    if ~app.PlayButton.Value
                        break
                    end
                    
                    setSliders(app, i);
                    setPsandLines(app,i);
                    app.tEditField.Value = app.time(i);
                    
                    drawnow
                    pause(app.pauseTime);
                end
            end
            app.PlayButton.Value = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, time, Global_Origin, Config_Datas)
            
            %----------------------------------------------------
            %-----------------データの入力------------------------
            %----------------------------------------------------
            app.time = time;
            app.Global_Origin = Global_Origin;
            app.Config_Datas = Config_Datas;
            app.sliders = app.thSlider;
            app.tEditField.Value = app.time(1);
            
            app.pauseTime = 0.05;
            
            %----------------------------------------------------
            %-----------------身体の表示--------------------------
            %----------------------------------------------------
            app.figure_ID = figure;
            app.ax = gca;
            show(app.Global_Origin, app.Config_Datas(1,:),'PreservePlot',true, 'Parent', app.ax);


            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            
            app.thSlider.Limits = [1, length(app.time)];
            drawnow
            pause(app.pauseTime);
            app.thSlider.MajorTickLabels = cellstr(num2str(time(app.thSlider.MajorTicks)));
            
%             app.ax.DeleteFcn = @() delete(app);
            
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                startTime = floor(app.thSlider.Value);
                if startTime ~= app.thSlider.Limits(2)
                    for i = [startTime:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time), length(app.time)]
                        
                        if ~app.PlayButton.Value
                            break
                        end
                        
                        setSliders(app, i);
                        setPsandLines(app,i);
                        app.tEditField.Value = app.time(i);
                        
                        drawnow
                        pause(app.pauseTime);
                    end
                else
                    for i = [1:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time),...
                            length(app.time)]
                        if ~app.PlayButton.Value
                            break
                        end
                        
                        setSliders(app, i);
                        setPsandLines(app,i);
                        app.tEditField.Value = app.time(i);
                        
                        drawnow
                        pause(app.pauseTime);
                    end
                end
                app.PlayButton.Value = false;
            end
        end

        % Value changing function: thSlider
        function sliderValueChanging(app, event)
            changingValue = event.Value;
            setSliders(app, floor(changingValue + 0.5));
            setPsandLines(app,floor(changingValue + 0.5));
            app.tEditField.Value = app.time(floor(changingValue + 0.5));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close(app.figure_ID)
            delete(app)
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            value = app.tEditField.Value;
            targetIndex = floor(value/(app.time(2)-app.time(1)));
            setSliders(app, targetIndex)
            setPsandLines(app,targetIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.thSlider = uislider(app.UIFigure);
            app.thSlider.Limits = [0 5];
            app.thSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimeAndData_Show(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end